classdef GUI1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        DropDown_2Label  matlab.ui.control.Label
        DropDown         matlab.ui.control.DropDown
        Panel            matlab.ui.container.Panel
        Button           matlab.ui.control.Button
        Button_2         matlab.ui.control.Button
        Label_2          matlab.ui.control.Label
        Label_3          matlab.ui.control.Label
        EditField        matlab.ui.control.EditField
        EditField_2      matlab.ui.control.EditField
        Button_3         matlab.ui.control.Button
        Button_4         matlab.ui.control.StateButton
        Image2           matlab.ui.control.Image
        Image2_2         matlab.ui.control.Image
        Image3_2         matlab.ui.control.Image
        Panel_2          matlab.ui.container.Panel
        Button_11        matlab.ui.control.Button
        Panel_3          matlab.ui.container.Panel
        Label_4          matlab.ui.control.Label
        Button_8         matlab.ui.control.Button
        Label_5          matlab.ui.control.Label
        EditField_4      matlab.ui.control.EditField
        Slider           matlab.ui.control.Slider
        EditField_5      matlab.ui.control.NumericEditField
        Image3           matlab.ui.control.Image
        AllrightsreservedIWASAKIGAKUENLabel  matlab.ui.control.Label
    end


    properties (Access = public)
       sample_FP = ''
       target_FP = ''
       resultPath = ''
       tol = ''
       t1
       f
    end
    
    methods (Access = public)
        function changeMenu(app,ID)%メニューの切換え
            app.Panel.Visible = 'off';
            app.Panel_2.Visible = 'off';
            if ID == 1
                app.Panel.Visible = 'on';
            elseif ID == 2
                app.Panel_2.Visible = 'on';
            end
        end
        
        function expansion(app,X,Y)
            PX = app.UIFigure.Position(1);
            PY = app.UIFigure.Position(2);
            SX = app.UIFigure.Position(3);
            SY = app.UIFigure.Position(4);
            app.UIFigure.Position = [PX-X PY-Y SX+X SY+Y];
        end
        
        function updateProgress(app,~,~)
            val = get_prog();
            app.f.Value = val(1) / val(2);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.resultPath = app.EditField_4.Value;
            app.tol = app.EditField_5.Value;
            set_prog([0.0001,1000]);
            app.t1 = timer('TimerFcn', @app.updateProgress, ...
                'Period',0.5, ...
                "TasksToExecute",inf, ...
                "ExecutionMode",'fixedDelay');
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            if app.Button_4.Value == true
                app.expansion(0,-250);
                app.Button_4.Value = false;
            end
            
            menu = find(string(app.DropDown.Items) == app.DropDown.Value);
            changeMenu(app,menu);
            
        end

        % Value changed function: Button_4
        function Button_4Pushed(app, event)
            if app.Button_4.Value == true
                app.expansion(0,250);
            else
                app.expansion(0,-250);
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            if app.Button_3.Enable == 'on'
                app.Button_3.Enable = 'off';
                app.f = uiprogressdlg(app.UIFigure,'Title','...','Message','処理中...');
                app.f.Value = 0;
                start(app.t1);
                try
                photo_evaluation(app.sample_FP,app.target_FP,app.resultPath,app.tol);
                catch
                msgbox({'処理中にエラーが発生しました。';'参照ディレクトリが正しいか、またはイメージ画像が入っているか確認してください。'},'エラー','error');
                end
                stop(app.t1);
                delete(app.f);
                set_prog([0.0001,1000]);
                app.Button_3.Enable = 'on';
                
                %close(app.UIFigure); 
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            dir = uigetdir;
            if ischar(dir) == true
                app.sample_FP = dir;
                app.EditField.Value = app.sample_FP;
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.sample_FP = app.EditField.Value;
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.target_FP = app.EditField_2.Value;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            dir = uigetdir;
            if ischar(dir) == true
                app.target_FP = dir;
                app.EditField_2.Value = app.target_FP;
            end
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            app.resultPath = app.EditField_4.Value;
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            dir = uigetdir;
            if ischar(dir) == true
               app.resultPath = dir;
               app.EditField_4.Value = app.resultPath; 
            end
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            app.tol = round(app.EditField_5.Value,2);
            app.Slider.Value = app.tol;
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            app.tol = round(event.Value,2);
            app.EditField_5.Value = app.tol;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'わけルンです';
            app.UIFigure.Icon = 'icon.png';
            app.DropDown_2Label = uilabel(app.UIFigure);
            app.DropDown_2Label.Text = '分類方法';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'写真ブレ分類', 'オムツ分類'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '写真ブレ分類';
            app.Panel = uipanel(app.UIFigure);
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '＋';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '＋';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '基準となる写真のフォルダを選択 ';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '分類したい写真のフォルダを選択';
            app.EditField = uieditfield(app.Panel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.Panel, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.Button_3 = uibutton(app.Panel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '実行';
            app.Button_4 = uibutton(app.Panel, 'state');
            app.Button_4.ValueChangedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '詳細設定';
            app.Image2 = uiimage(app.Panel);
            app.Image2.ImageSource = '3.png';
            app.Image2_2 = uiimage(app.Panel);
            app.Image2_2.ImageSource = '3.png';
            app.Image3_2 = uiimage(app.Panel);
            app.Image3_2.ImageSource = '2.png';
            app.Panel_2 = uipanel(app.Panel);
            app.Button_11 = uibutton(app.Panel_2, 'push');
            app.Button_11.Text = '実行';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Tooltip = {''};
            app.Label_4 = uilabel(app.Panel_3);
            app.Label_4.Text = ' 許容値 :';
            app.Button_8 = uibutton(app.Panel_3, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '＋';
            app.Label_5 = uilabel(app.Panel_3);
            app.Label_5.Text = '結果の保存場所を指定する';
            app.EditField_4 = uieditfield(app.Panel_3, 'text');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Value = './result';
            app.Slider = uislider(app.Panel_3);
            app.Slider.Limits = [-1 1];
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 0.25;
            app.EditField_5 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_5.Limits = [-1 1];
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_5.Value = 0.25;
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = '2.png';
            app.AllrightsreservedIWASAKIGAKUENLabel = uilabel(app.UIFigure);
            app.AllrightsreservedIWASAKIGAKUENLabel.Text = 'All rights reserved　IWASAKI　GAKUEN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end