classdef GUI2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SecurityUIFigure  matlab.ui.Figure
        Button            matlab.ui.control.Button
        passEditField     matlab.ui.control.EditField
        Label             matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            passin = app.passEditField.Value;
            passin = sha256(passin);
            if(strcmp(passin,'61559AA32D98A342520A5DF97A5ACC618229A8EA5D0871C55E05E5CD8C8B57B6')==0)
                msgbox('パスワードが違います','エラー','error');
                return
            else
                app.mainApp = GUI1();
                delete(app.SecurityUIFigure);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SecurityUIFigure = uifigure('Visible', 'off');
            app.SecurityUIFigure.Name = 'Security';
            app.SecurityUIFigure.Icon = 'icon.png';
            app.Button = uibutton(app.SecurityUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '認証';
            app.passEditField = uieditfield(app.SecurityUIFigure, 'text');
            app.Label = uilabel(app.SecurityUIFigure);
            app.Label.Text = 'このアプリを使用するにはパスワードを入力してください';
            % Show the figure after all components are created
            app.SecurityUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SecurityUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SecurityUIFigure)
        end
    end
end